classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VelocidadAngularGauge           matlab.ui.control.Gauge
        VelocidadAngularradsGaugeLabel  matlab.ui.control.Label
        Mesa4TextArea                   matlab.ui.control.TextArea
        Mesa4TextAreaLabel              matlab.ui.control.Label
        Mesa3TextArea                   matlab.ui.control.TextArea
        Mesa3TextAreaLabel              matlab.ui.control.Label
        Mesa1TextArea                   matlab.ui.control.TextArea
        Mesa1TextArea_7Label            matlab.ui.control.Label
        Mesa5TextArea                   matlab.ui.control.TextArea
        Mesa5TextAreaLabel              matlab.ui.control.Label
        Mesa2TextArea                   matlab.ui.control.TextArea
        Mesa2TextAreaLabel              matlab.ui.control.Label
        VelocidadLinealGauge            matlab.ui.control.Gauge
        VelocidadLinealcmsGaugeLabel    matlab.ui.control.Label
        Mesa3Button                     matlab.ui.control.Button
        Mesa2Button                     matlab.ui.control.Button
        Mesa4Button                     matlab.ui.control.Button
        Mesa5Button                     matlab.ui.control.Button
        ConexionLamp                    matlab.ui.control.Lamp
        DesconectarButton               matlab.ui.control.Button
        ConectarButton                  matlab.ui.control.Button
        Mesa1Button                     matlab.ui.control.Button
        TituloLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        tablePublisher;
        table = 'None';
        dish = 'None';
        velSubscriber;
        TimerObject;
        period = 0.01;
    end
    
    methods (Access = private)
        
        function timerCallback(app)
            
            velMsg = receive(app.velSubscriber);

            %disp(velMsg.Twist.Twist.Linear)

            if isempty(velMsg)~=true

                linear_vel = velMsg.Twist.Twist.Linear.X;

                angular_vel = velMsg.Twist.Twist.Angular.Z;

                app.VelocidadLinealGauge.Value = linear_vel*100;

                app.VelocidadAngularGauge.Value = angular_vel*100;


            end

            msg = rosmessage(app.tablePublisher);
            msg.Data = app.table;
            send(app.tablePublisher,msg);
            if strcmp(app.table,'None') ~= 1 && strcmp(app.dish,'None') ~= 1

                text = sprintf('Enviando plato: %s a la mesa: %s',app.dish,app.table);
                msgbox(text,'Información','modal');

            end

            app.dish = 'None';
            app.table = 'None';
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TimerObject = timer('ExecutionMode','fixedRate','Period',app.period,'TimerFcn', @(src,event) timerCallback(app));
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)

            ipAdress = "192.168.18.41";
            rosinit(ipAdress);
            app.ConexionLamp.Color = [0 1 0];
            %app.msg.table = -1;
            app.tablePublisher = rospublisher("/table","DataFormat","struct");
            app.velSubscriber = rossubscriber("/odom","DataFormat","struct");
            start(app.TimerObject);
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            app.ConexionLamp.Color = [1 0 0];
            stop(app.TimerObject);
            clear;
            
            rosshutdown;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            DesconectarButtonPushed(app,event);
            delete(app)
            
        end

        % Button pushed function: Mesa1Button
        function Mesa1ButtonPushed(app, event)
            
            if strlength(char(app.Mesa1TextArea.Value)) > 1

                app.dish = char(app.Mesa1TextArea.Value);

            end
            
            app.Mesa1TextArea.Value="";
            app.table = 'table1';
            
        end

        % Button pushed function: Mesa2Button
        function Mesa2ButtonPushed(app, event)
            if strlength(char(app.Mesa2TextArea.Value)) > 1

                app.dish = char(app.Mesa2TextArea.Value);

            end
            app.Mesa2TextArea.Value="";
            app.table = 'table2';
        end

        % Button pushed function: Mesa3Button
        function Mesa3ButtonPushed(app, event)
            if strlength(char(app.Mesa3TextArea.Value)) > 1

                app.dish = char(app.Mesa3TextArea.Value);

            end
            app.Mesa3TextArea.Value="";
            app.table = 'table3';
        end

        % Button pushed function: Mesa4Button
        function Mesa4ButtonPushed(app, event)
            if strlength(char(app.Mesa4TextArea.Value)) > 1

                app.dish = char(app.Mesa4TextArea.Value);

            end
            app.Mesa4TextArea.Value="";
            app.table = 'table4';
        end

        % Button pushed function: Mesa5Button
        function Mesa5ButtonPushed(app, event)
            if strlength(char(app.Mesa5TextArea.Value)) > 1

                app.dish = char(app.Mesa5TextArea.Value);

            end
            app.Mesa5TextArea.Value="";
            app.table = 'table5';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TituloLabel = uilabel(app.UIFigure);
            app.TituloLabel.Text = 'Interfaz de usuario';
            app.Mesa1Button = uibutton(app.UIFigure, 'push');
            app.Mesa1Button.ButtonPushedFcn = createCallbackFcn(app, @Mesa1ButtonPushed, true);
            app.Mesa1Button.Text = 'Mesa 1';
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.UIFigure, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.ConexionLamp = uilamp(app.UIFigure);
            app.Mesa5Button = uibutton(app.UIFigure, 'push');
            app.Mesa5Button.ButtonPushedFcn = createCallbackFcn(app, @Mesa5ButtonPushed, true);
            app.Mesa5Button.Text = 'Mesa 5';
            app.Mesa4Button = uibutton(app.UIFigure, 'push');
            app.Mesa4Button.ButtonPushedFcn = createCallbackFcn(app, @Mesa4ButtonPushed, true);
            app.Mesa4Button.Text = 'Mesa 4';
            app.Mesa2Button = uibutton(app.UIFigure, 'push');
            app.Mesa2Button.ButtonPushedFcn = createCallbackFcn(app, @Mesa2ButtonPushed, true);
            app.Mesa2Button.Text = 'Mesa 2';
            app.Mesa3Button = uibutton(app.UIFigure, 'push');
            app.Mesa3Button.ButtonPushedFcn = createCallbackFcn(app, @Mesa3ButtonPushed, true);
            app.Mesa3Button.Text = 'Mesa 3';
            app.VelocidadLinealcmsGaugeLabel = uilabel(app.UIFigure);
            app.VelocidadLinealcmsGaugeLabel.Text = 'Velocidad Lineal (cm/s)';
            app.VelocidadLinealGauge = uigauge(app.UIFigure, 'circular');
            app.VelocidadLinealGauge.Limits = [-65 65];
            app.VelocidadLinealGauge.MinorTicks = [];
            app.Mesa2TextAreaLabel = uilabel(app.UIFigure);
            app.Mesa2TextAreaLabel.Text = 'Mesa 2';
            app.Mesa2TextArea = uitextarea(app.UIFigure);
            app.Mesa5TextAreaLabel = uilabel(app.UIFigure);
            app.Mesa5TextAreaLabel.Text = 'Mesa 5';
            app.Mesa5TextArea = uitextarea(app.UIFigure);
            app.Mesa1TextArea_7Label = uilabel(app.UIFigure);
            app.Mesa1TextArea_7Label.Text = 'Mesa 1';
            app.Mesa1TextArea = uitextarea(app.UIFigure);
            app.Mesa3TextAreaLabel = uilabel(app.UIFigure);
            app.Mesa3TextAreaLabel.Text = 'Mesa 3';
            app.Mesa3TextArea = uitextarea(app.UIFigure);
            app.Mesa4TextAreaLabel = uilabel(app.UIFigure);
            app.Mesa4TextAreaLabel.Text = 'Mesa 4';
            app.Mesa4TextArea = uitextarea(app.UIFigure);
            app.VelocidadAngularradsGaugeLabel = uilabel(app.UIFigure);
            app.VelocidadAngularradsGaugeLabel.Text = 'Velocidad Angular (rad/s)';
            app.VelocidadAngularGauge = uigauge(app.UIFigure, 'circular');
            app.VelocidadAngularGauge.Limits = [-3 3];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end